classdef mainGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NeoLCOUIFigure                  matlab.ui.Figure
        LogoPanel                       matlab.ui.container.Panel
        Image2                          matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        TextArea                        matlab.ui.control.TextArea
        TabGroup                        matlab.ui.container.TabGroup
        ImportfileTab                   matlab.ui.container.Tab
        Panel                           matlab.ui.container.Panel
        ImportaconfigurationfileoptionalPanel  matlab.ui.container.Panel
        ordirectlyspecifythefilefullpathEditField_2  matlab.ui.control.EditField
        ordirectlyspecifythefilefullpathEditField_2Label  matlab.ui.control.Label
        LaunchButton                    matlab.ui.control.Button
        Label_2                         matlab.ui.control.Label
        NextButton                      matlab.ui.control.Button
        LoadconfigurationfileButton     matlab.ui.control.Button
        ImporttheFEMmodelPanel          matlab.ui.container.Panel
        ordirectlyspecifythefilefullpathEditField  matlab.ui.control.EditField
        ordirectlyspecifythefilefullpathEditFieldLabel  matlab.ui.control.Label
        VerifyFEMmodelButton            matlab.ui.control.Button
        LoadaFEMmodelforsubsequentanalysisLabel  matlab.ui.control.Label
        LoadmodelfilefromguiButton      matlab.ui.control.Button
        CreatemodalbaseTab              matlab.ui.container.Tab
        DefinesearchparametersfortheoptimalbasePanel  matlab.ui.container.Panel
        ChoosethebasePanel              matlab.ui.container.Panel
        NextButton_7                    matlab.ui.control.Button
        FictitiousmassEditField         matlab.ui.control.NumericEditField
        FictitiousmassEditFieldLabel    matlab.ui.control.Label
        StiffnessfactorEditField        matlab.ui.control.NumericEditField
        StiffnessfactorEditFieldLabel   matlab.ui.control.Label
        NumberofmodesEditField          matlab.ui.control.NumericEditField
        NumberofmodesEditFieldLabel     matlab.ui.control.Label
        ConfirmchoiceButton             matlab.ui.control.StateButton
        maxFreqInterestEditField        matlab.ui.control.NumericEditField
        maxFreqInterestEditFieldLabel   matlab.ui.control.Label
        fictitiousMassEditField         matlab.ui.control.EditField
        fictitiousMassEditFieldLabel    matlab.ui.control.Label
        stiffnessFactorEditField        matlab.ui.control.EditField
        stiffnessFactorEditFieldLabel   matlab.ui.control.Label
        nModesEditField                 matlab.ui.control.EditField
        nModesEditFieldLabel            matlab.ui.control.Label
        referenceNModesEditField        matlab.ui.control.NumericEditField
        referenceNModesEditFieldLabel   matlab.ui.control.Label
        kNominalEditField_3             matlab.ui.control.EditField
        kNominalEditField_3Label        matlab.ui.control.Label
        gapPointsEditField              matlab.ui.control.EditField
        gapPointsEditFieldLabel         matlab.ui.control.Label
        ComputebaseButton               matlab.ui.control.Button
        labelMaxFreqInterest            matlab.ui.control.Label
        labelFictitiousMass             matlab.ui.control.Label
        VectoroffactorsthatmultipliesthenominalstiffnesstobetestedLabel  matlab.ui.control.Label
        VectorofmodestobetestedLabel    matlab.ui.control.Label
        labelOptimumKnown               matlab.ui.control.Label
        OptimumknownCheckBox            matlab.ui.control.CheckBox
        labelReferenceNModes            matlab.ui.control.Label
        labelkNominal                   matlab.ui.control.Label
        labelgapPoints                  matlab.ui.control.Label
        CreateaerodynamicdatabaseTab    matlab.ui.container.Tab
        AllLabel                        matlab.ui.control.Label
        dynVLMLabel                     matlab.ui.control.Label
        noLinCurvatureLabel             matlab.ui.control.Label
        dynVLMtypeLabel                 matlab.ui.control.Label
        meshTypeLabel                   matlab.ui.control.Label
        NextButton_3                    matlab.ui.control.Button
        TrimlistEditField               matlab.ui.control.EditField
        TrimlistEditFieldLabel          matlab.ui.control.Label
        CreatethedatabaseButton         matlab.ui.control.Button
        OptionusedtoselectthetrimstobeusedfortheDLMcorrectionLabel  matlab.ui.control.Label
        OptionusedtodefinethetypeofcorrectionLabel  matlab.ui.control.Label
        FlagtoactivatetheuseofthecorrectedDLMLabel  matlab.ui.control.Label
        labelFlagToAvoidLinCurvature    matlab.ui.control.Label
        CheckBox_3                      matlab.ui.control.CheckBox
        ButtonGroup_2                   matlab.ui.container.ButtonGroup
        unsteadyButton                  matlab.ui.control.RadioButton
        quasiSteadyButton               matlab.ui.control.RadioButton
        steadyButton                    matlab.ui.control.RadioButton
        CheckBox_2                      matlab.ui.control.CheckBox
        CheckBox                        matlab.ui.control.CheckBox
        ButtonGroup                     matlab.ui.container.ButtonGroup
        fullflatButton                  matlab.ui.control.RadioButton
        flatButton                      matlab.ui.control.RadioButton
        TheflatmeshtypealsoconsiderstheLabel  matlab.ui.control.Label
        DescribingfunctionanalysisTab   matlab.ui.container.Tab
        AerodynamicparametersPanel      matlab.ui.container.Panel
        SelectedtrimIDforVLMcorrectionEditField_3  matlab.ui.control.NumericEditField
        SelectedtrimIDforVLMcorrectionEditField_3Label  matlab.ui.control.Label
        AxesusedLabel                   matlab.ui.control.Label
        TypeLabel_2                     matlab.ui.control.Label
        DynVLMLabel                     matlab.ui.control.Label
        MachnumberEditField             matlab.ui.control.NumericEditField
        MachnumberEditFieldLabel        matlab.ui.control.Label
        DensityEditField                matlab.ui.control.NumericEditField
        DensityEditField_2Label         matlab.ui.control.Label
        ButtonGroup_7                   matlab.ui.container.ButtonGroup
        bodyanglesButton                matlab.ui.control.RadioButton
        inertialsButton                 matlab.ui.control.RadioButton
        bodyButton                      matlab.ui.control.RadioButton
        ButtonGroup_6                   matlab.ui.container.ButtonGroup
        unsteadyButton_2                matlab.ui.control.RadioButton
        quasiSteadyButton_2             matlab.ui.control.RadioButton
        steadyButton_2                  matlab.ui.control.RadioButton
        CheckBox_7                      matlab.ui.control.CheckBox
        LaunchdescribingfunctionsimulationPanel  matlab.ui.container.Panel
        Label_5                         matlab.ui.control.Label
        PleasechecktheinputsalsointhefollowingtabsLabel  matlab.ui.control.Label
        Performallanalyses              matlab.ui.control.Button
        NextButton_4                    matlab.ui.control.Button
        PerformdescribingfunctionanalysesButton  matlab.ui.control.Button
        StructuralparametersPanel       matlab.ui.container.Panel
        AmplitudedefinitionLabel        matlab.ui.control.Label
        ModestoplotinVgfdiagramsEditField  matlab.ui.control.EditField
        ModestoplotinVgfdiagramsEditFieldLabel  matlab.ui.control.Label
        ButtonGroup_5                   matlab.ui.container.ButtonGroup
        stdButton                       matlab.ui.control.RadioButton
        rmsButton                       matlab.ui.control.RadioButton
        maxPeakButton_2                 matlab.ui.control.RadioButton
        NominalstiffnessEditField_2     matlab.ui.control.EditField
        NominalstiffnessEditField_2Label  matlab.ui.control.Label
        GatherfromoptimalbaseoptionsButton  matlab.ui.control.Button
        GapEditField                    matlab.ui.control.EditField
        GapEditField_2Label             matlab.ui.control.Label
        GlobalparmetersPanel            matlab.ui.container.Panel
        IntroducegravityloadsLabel_2    matlab.ui.control.Label
        Theformatispoint1sorgdof12345or6loadpoint2Label_2  matlab.ui.control.Label
        IntroducestructuralloadsLabel_2  matlab.ui.control.Label
        GravitydirectionEditField_2     matlab.ui.control.EditField
        GravitydirectionEditField_2Label  matlab.ui.control.Label
        StructuralloadsEditField        matlab.ui.control.EditField
        StructuralloadsEditFieldLabel   matlab.ui.control.Label
        CheckBox_20                     matlab.ui.control.CheckBox
        CheckBox_19                     matlab.ui.control.CheckBox
        SelectedtrimIDforloadsEditField_3  matlab.ui.control.NumericEditField
        SelectedtrimIDforloadsEditField_3Label  matlab.ui.control.Label
        FluttersearchmethodLabel        matlab.ui.control.Label
        SearchquenchpointLabel          matlab.ui.control.Label
        TrimtypeLabel                   matlab.ui.control.Label
        RecomputebaseLabel              matlab.ui.control.Label
        IntroduceflightloadsLabel       matlab.ui.control.Label
        VstepEditField                  matlab.ui.control.NumericEditField
        VstepEditFieldLabel             matlab.ui.control.Label
        VminEditField                   matlab.ui.control.NumericEditField
        VminEditFieldLabel              matlab.ui.control.Label
        VmaxEditField                   matlab.ui.control.NumericEditField
        VmaxEditFieldLabel              matlab.ui.control.Label
        nKeqEditField                   matlab.ui.control.NumericEditField
        nKeqEditFieldLabel              matlab.ui.control.Label
        maxKeqEditField                 matlab.ui.control.NumericEditField
        maxKeqEditFieldLabel            matlab.ui.control.Label
        ButtonGroup_4                   matlab.ui.container.ButtonGroup
        PKButton                        matlab.ui.control.RadioButton
        PK0Button                       matlab.ui.control.RadioButton
        CheckBox_6                      matlab.ui.control.CheckBox
        CheckBox_5                      matlab.ui.control.CheckBox
        ButtonGroup_3                   matlab.ui.container.ButtonGroup
        restrainedButton                matlab.ui.control.RadioButton
        groundedButton                  matlab.ui.control.RadioButton
        meanAxesButton                  matlab.ui.control.RadioButton
        rigidButton                     matlab.ui.control.RadioButton
        CheckBox_4                      matlab.ui.control.CheckBox
        Label_3                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        TimemarchinganalysisTab         matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        PreprocesssimulationTab         matlab.ui.container.Tab
        SelectedtrimIDforVLMcorrectionEditField_4  matlab.ui.control.NumericEditField
        SelectedtrimIDforVLMcorrectionEditField_4Label  matlab.ui.control.Label
        PlotstochecktheapproximationLabel  matlab.ui.control.Label
        DynVLMtypeLabel_2               matlab.ui.control.Label
        DynVLMLabel_2                   matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        DonotusemodeButton              matlab.ui.control.StateButton
        ChosenmodesEditField            matlab.ui.control.EditField
        ChosenmodesEditFieldLabel       matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        ROMorderEditField               matlab.ui.control.NumericEditField
        ROMorderEditFieldLabel          matlab.ui.control.Label
        UsemodeButton                   matlab.ui.control.StateButton
        NextButton_6                    matlab.ui.control.Button
        CreatetimemarchingmodelButton   matlab.ui.control.Button
        GapBehaviourEditField           matlab.ui.control.EditField
        GapBehaviourEditFieldLabel      matlab.ui.control.Label
        MonitorPointsEditField          matlab.ui.control.EditField
        MonitorPointsEditFieldLabel     matlab.ui.control.Label
        CheckBox_9                      matlab.ui.control.CheckBox
        MachnumberEditField_2           matlab.ui.control.NumericEditField
        MachnumberEditField_2Label      matlab.ui.control.Label
        ButtonGroup_8                   matlab.ui.container.ButtonGroup
        unsteadyButton_3                matlab.ui.control.RadioButton
        quasiSteadyButton_3             matlab.ui.control.RadioButton
        steadyButton_3                  matlab.ui.control.RadioButton
        CheckBox_8                      matlab.ui.control.CheckBox
        UIAxes                          matlab.ui.control.UIAxes
        PerformanalysesTab              matlab.ui.container.Tab
        LaunchtimemarchingsimulationPanel  matlab.ui.container.Panel
        NumberofprocessorsEditField     matlab.ui.control.NumericEditField
        NumberofprocessorsEditFieldLabel  matlab.ui.control.Label
        NextButton_5                    matlab.ui.control.Button
        PerformtimemarchinganalysesButton  matlab.ui.control.Button
        OptionsforthesimulationPanel    matlab.ui.container.Panel
        EditField                       matlab.ui.control.EditField
        Label_7                         matlab.ui.control.Label
        TypeLabel_3                     matlab.ui.control.Label
        ButtonGroup_11                  matlab.ui.container.ButtonGroup
        splineButton_2                  matlab.ui.control.RadioButton
        linearButton_2                  matlab.ui.control.RadioButton
        previousButton_2                matlab.ui.control.RadioButton
        GapdimensionsEditField          matlab.ui.control.EditField
        GapdimensionsEditFieldLabel     matlab.ui.control.Label
        gatherfromdescribingfunctionoptionsButton  matlab.ui.control.Button
        NominalstiffnessEditField       matlab.ui.control.EditField
        NominalstiffnessEditFieldLabel  matlab.ui.control.Label
        NonlinearitydefinitionLabel     matlab.ui.control.Label
        InterpolationLabel              matlab.ui.control.Label
        BehaviourLabel                  matlab.ui.control.Label
        ButtonGroup_10                  matlab.ui.container.ButtonGroup
        splineButton                    matlab.ui.control.RadioButton
        linearButton                    matlab.ui.control.RadioButton
        previousButton                  matlab.ui.control.RadioButton
        InitialamplitudefactorEditField  matlab.ui.control.NumericEditField
        InitialamplitudefactorEditFieldLabel  matlab.ui.control.Label
        ButtonGroup_9                   matlab.ui.container.ButtonGroup
        descresingButton                matlab.ui.control.RadioButton
        increasingButton                matlab.ui.control.RadioButton
        bothButton                      matlab.ui.control.RadioButton
        SpeedvectorEditField            matlab.ui.control.EditField
        SpeedvectorEditFieldLabel       matlab.ui.control.Label
        DensityEditField_2              matlab.ui.control.NumericEditField
        DensityEditField_2Label_2       matlab.ui.control.Label
        OptionsfortheoutputsPanel       matlab.ui.container.Panel
        PlotfixedpointsofthesystemLabel  matlab.ui.control.Label
        PlotmaxrealeigenvalueLabel      matlab.ui.control.Label
        AmplitudedefinitionfortheLCOLabel  matlab.ui.control.Label
        RecoputedequidistandassimulinkusesanadaptivestrategyLabel  matlab.ui.control.Label
        nooutoputclculationLabel        matlab.ui.control.Label
        CheckBox_11                     matlab.ui.control.CheckBox
        CheckBox_10                     matlab.ui.control.CheckBox
        ButtonGroup_12                  matlab.ui.container.ButtonGroup
        maxPeakButton                   matlab.ui.control.RadioButton
        stdButton_2                     matlab.ui.control.RadioButton
        rmsButton_2                     matlab.ui.control.RadioButton
        MaxfrequencyofinterestEditField  matlab.ui.control.NumericEditField
        MaxfrequencyofinterestEditFieldLabel  matlab.ui.control.Label
        OverlapofFFTwindowsEditField    matlab.ui.control.NumericEditField
        OverlapofFFTwindowsEditFieldLabel  matlab.ui.control.Label
        timestepforFFTEditField         matlab.ui.control.NumericEditField
        timestepforFFTEditFieldLabel    matlab.ui.control.Label
        FFTwindowlengthEditField        matlab.ui.control.NumericEditField
        FFTwindowlengthEditFieldLabel   matlab.ui.control.Label
        NumberofFFTwindowsEditField     matlab.ui.control.NumericEditField
        NumberofFFTwindowsEditFieldLabel  matlab.ui.control.Label
        InitialisationtimeEditField     matlab.ui.control.NumericEditField
        InitialisationtimeEditFieldLabel  matlab.ui.control.Label
        OptionsforthesteadyloadsPanel   matlab.ui.container.Panel
        GravitydirectionEditField       matlab.ui.control.EditField
        GravitydirectionEditFieldLabel  matlab.ui.control.Label
        IntroducegravityloadsLabel      matlab.ui.control.Label
        CheckBox_18                     matlab.ui.control.CheckBox
        Theformatispoint1sorgdof12345or6loadpoint2Label  matlab.ui.control.Label
        IntroducestructuralloadsLabel   matlab.ui.control.Label
        TrimtypeforloadsLabel           matlab.ui.control.Label
        IntroduceflightloadsLabel_2     matlab.ui.control.Label
        StructuralloadsDefinedEditField  matlab.ui.control.EditField
        StructuralloadsTheformatispoint1sorgdof12345or6loadpoint2Label  matlab.ui.control.Label
        CheckBox_13                     matlab.ui.control.CheckBox
        SelectedtrimIDforloadsEditField  matlab.ui.control.NumericEditField
        SelectedtrimIDforloadsEditFieldLabel  matlab.ui.control.Label
        ButtonGroup_13                  matlab.ui.container.ButtonGroup
        restrainedButton_2              matlab.ui.control.RadioButton
        rigidButton_2                   matlab.ui.control.RadioButton
        groundedButton_2                matlab.ui.control.RadioButton
        meanAxesButton_2                matlab.ui.control.RadioButton
        CheckBox_12                     matlab.ui.control.CheckBox
        MainplotsTab                    matlab.ui.container.Tab
        ExitsavingconfigurationButton   matlab.ui.control.Button
        ExitButton                      matlab.ui.control.Button
        PlottypeLabel                   matlab.ui.control.Label
        NormalisealsotheforcestorquesatthegappointsbythegapsizeLabel  matlab.ui.control.Label
        NormalisealsothemonitorpointswiththegapsizeLabel  matlab.ui.control.Label
        PlotasinglegapforthedescribingfunctionresultsLabel  matlab.ui.control.Label
        NonlinearitytobeusedEditField   matlab.ui.control.NumericEditField
        NonlinearitytobeusedEditFieldLabel  matlab.ui.control.Label
        HalfgapnormalisationfortheLCOamplitudeLabel  matlab.ui.control.Label
        ComputemainoutputsButton        matlab.ui.control.Button
        ButtonGroup_14                  matlab.ui.container.ButtonGroup
        BifurcationButton               matlab.ui.control.RadioButton
        SingleButton                    matlab.ui.control.RadioButton
        CheckBox_17                     matlab.ui.control.CheckBox
        CheckBox_16                     matlab.ui.control.CheckBox
        ValueforwindspeednormalisationEditField  matlab.ui.control.NumericEditField
        ValueforwindspeednormalisationEditFieldLabel  matlab.ui.control.Label
        CheckBox_15                     matlab.ui.control.CheckBox
        OtherwisethefullgapisusedforthenormalisationLabel  matlab.ui.control.Label
        CheckBox_14                     matlab.ui.control.CheckBox
        UIAxes5                         matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        filename_sma
        filepath
        optimalBaseOptions
        aeroDatabaseOptions
        preprocessTimeMarchingOptions
        timeMarchingOptions
        timeMarchingResults
        describingFunctionOptions
        plotOptions
        inputData
        reducedBasis
        struData
        model
        struData_stiff
        model_stiff
        options
        aeroData
        logFile
        describingFunctionResults
        modelForIntegration
        configname
        configpath
        valuesToSave
        selectionsToSave
    end
    
    methods (Access = private)
        
        function parseConfig(app)

            state = load(app.ordirectlyspecifythefilefullpathEditField_2.Value,'state');
            state = state.state;
            for i = 1:length(app.valuesToSave)
                if isfield(state,app.valuesToSave{i})
                    app.(app.valuesToSave{i}).Value = state.(app.valuesToSave{i}).Value;
                else
                    fprintf(1,"WARNING: Option %s was not found in the configuration file, set to default\n", app.valuesToSave{i});
                end
            end
            for i = 1:length(app.selectionsToSave)
                if isfield(state,app.selectionsToSave{i})
                    for j = 1:length(app.(app.selectionsToSave{i}).Children)
                        if strcmp(app.(app.selectionsToSave{i}).Children(j).Text, state.(app.selectionsToSave{i}).SelectedObject.Text)
                            app.(app.selectionsToSave{i}).Children(j).Value = 1;
                        else
                            app.(app.selectionsToSave{i}).Children(j).Value = 0;
                        end
                    end
                else
                    fprintf(1,"WARNING: Option %s was not found in the configuration file, set to default\n", app.selectionsToSave{i});
                end
            end

        end
        
        function dumpConfig(app)
            
            state = struct();
            for i = 1:length(app.valuesToSave)
                state.(app.valuesToSave{i}).Value = app.(app.valuesToSave{i}).Value;
            end
            for i = 1:length(app.selectionsToSave)
                state.(app.selectionsToSave{i}).SelectedObject = app.(app.selectionsToSave{i}).SelectedObject;
            end
            save('configuration.mat','state')

        end

        function prepareDiary(app)
            if exist('log.txt','file')
                delete('log.txt');
            end
            app.logFile = strcat(pwd,filesep,'log.txt');
        end
        
        function printDiary(app)
            log = regexp(fileread(app.logFile), '\r?\n', 'split');
            log = log(1:end-1);
            app.TextArea.Value = log;
            scroll(app.TextArea, 'bottom')
        end

        function saveData(app)
            data.describingFunctionOptions = app.describingFunctionOptions;
            data.describingFunctionResults = app.describingFunctionResults;
            data.timeMarchingOptions = app.timeMarchingOptions;
            data.timeMarchingResults = app.timeMarchingResults;
            data.preprocessTimeMarchingOptions = app.preprocessTimeMarchingOptions;
            data.plotOptions = app.plotOptions;
            save("data.mat","data")
        end
    end
    
    methods(Access = private, Static)

        function trimList = getTrim(string)
            if isempty(string)
                trimList = [];
            else
                trimList = eval(string);
            end
        end

        function result = selectType(string)
            if strcmp(string,'previous')
                result = 0;
            elseif strcmp(string,'linear')
                result = 1;
            else
                result = 2;
            end
        end

        function result = stringFromCellArray(array)
            result = [];
            for i = 1:length(array)
                result = strcat(result,"[",num2str(array{i}),"];");
            end
            result = char(result);
            result = string(result(1:end-1));
            result = strcat("{",result,"}");
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            pause(4)
            app.LogoPanel.Visible = 'off';
            app.NextButton_3.Visible = 'off';
            app.NextButton_4.Visible = 'off';
            app.NextButton_5.Visible = 'off';
            app.NextButton_6.Visible = 'off';
            app.NextButton_7.Visible = 'off';
            app.UIAxes.Visible = 'off';
            app.UsemodeButton.Visible = 'off';
            app.DonotusemodeButton.Visible = 'off';
            app.ChoosethebasePanel.Visible = 'off';
            app.ExitButton.Visible = 'off';
            app.ExitsavingconfigurationButton.Visible = 'off';
            app.selectionsToSave = {'ButtonGroup_2', 'ButtonGroup', 'ButtonGroup_7', 'ButtonGroup_6', 'ButtonGroup_5', ...
                'ButtonGroup_4', 'ButtonGroup_3', 'ButtonGroup_8', 'ButtonGroup_11', 'ButtonGroup_10', 'ButtonGroup_9', ...
                'ButtonGroup_12', 'ButtonGroup_13', 'ButtonGroup_14'};
            app.valuesToSave = {'FictitiousmassEditField', 'StiffnessfactorEditField', 'NumberofmodesEditField', 'MaxfrequencyofinterestEditField', ...
                'fictitiousMassEditField', 'stiffnessFactorEditField', 'nModesEditField', 'referenceNModesEditField', ...
                'kNominalEditField_3', 'gapPointsEditField', 'OptimumknownCheckBox', 'TrimlistEditField', 'CheckBox_3', ...
                'CheckBox_2', 'CheckBox', 'SelectedtrimIDforVLMcorrectionEditField_3', 'MachnumberEditField', 'DensityEditField', ...
                'CheckBox_7', 'ModestoplotinVgfdiagramsEditField', 'NominalstiffnessEditField_2', 'GapEditField', 'SelectedtrimIDforloadsEditField_3', ...
                'VstepEditField', 'VminEditField', 'VmaxEditField', 'nKeqEditField', 'maxKeqEditField', 'CheckBox_6', 'CheckBox_5', ...
                'CheckBox_4', 'SelectedtrimIDforVLMcorrectionEditField_4', 'ChosenmodesEditField', 'ROMorderEditField', 'GapBehaviourEditField', ...
                'MonitorPointsEditField', 'CheckBox_9', 'MachnumberEditField_2', 'CheckBox_8', 'EditField', 'GapdimensionsEditField', ...
                'NominalstiffnessEditField', 'InitialamplitudefactorEditField', 'SpeedvectorEditField', 'DensityEditField_2', ...
                'CheckBox_11', 'CheckBox_10', 'MaxfrequencyofinterestEditField', 'OverlapofFFTwindowsEditField', 'timestepforFFTEditField', ...
                'FFTwindowlengthEditField', 'NumberofFFTwindowsEditField', 'InitialisationtimeEditField', 'StructuralloadsDefinedEditField', ...
                'CheckBox_13', 'SelectedtrimIDforloadsEditField', 'CheckBox_12', 'NonlinearitytobeusedEditField', 'CheckBox_17', ...
                'CheckBox_16', 'ValueforwindspeednormalisationEditField', 'CheckBox_15', 'CheckBox_14', 'CheckBox_18', 'GravitydirectionEditField', ...
                'CheckBox_19', 'CheckBox_20', 'StructuralloadsEditField', 'GravitydirectionEditField_2', 'NumberofprocessorsEditField'};
        end

        % Button pushed function: LoadmodelfilefromguiButton
        function LoadmodelfilefromguiButtonPushed(app, event)
            [app.filename_sma, app.filepath] = uigetfile('*','Specify the main FEM file (includes can be used in the file)');
            app.ordirectlyspecifythefilefullpathEditField.Value = strcat(app.filepath,filesep,app.filename_sma);
        end

        % Button pushed function: VerifyFEMmodelButton
        function VerifyFEMmodelButtonPushed(app, event)
            ver = get_neolco_version(true);
            if isempty(app.filename_sma)
                file = app.ordirectlyspecifythefilefullpathEditField.Value;
                [app.filepath,name,ext] = fileparts(file);
                app.filename_sma = strcat(name,ext);
            end
            if ~isempty(app.filepath)
                cd(app.filepath)
            end
            app.prepareDiary()
            diary(app.logFile)
            diary on
            app.inputData = readSmartcadFile(app.filename_sma);
            app.printDiary()
            app.VerifyFEMmodelButton.Text = 'Verified';
            app.VerifyFEMmodelButton.BackgroundColor = [0.65,0.69,0.79];
            app.Panel.Visible = 'off';
        end

        % Button pushed function: LoadconfigurationfileButton
        function LoadconfigurationfileButtonPushed(app, event)
            [app.configname, app.configpath] = uigetfile('Specify the configuration file');
            app.ordirectlyspecifythefilefullpathEditField_2.Value = strcat(app.configpath,filesep,app.configname);
            app.parseConfig()
        end

        % Button pushed function: ComputebaseButton
        function ComputebaseButtonPushed(app, event)
            app.ComputebaseButton.Text = 'Creating the base';
            app.ComputebaseButton.BackgroundColor = [0.65,0.69,0.79];
            app.optimalBaseOptions.useInApp = 1;
            app.optimalBaseOptions.optimumKnown = app.OptimumknownCheckBox.Value;
            app.optimalBaseOptions.nModes = eval(app.nModesEditField.Value);
            app.optimalBaseOptions.fictitiousMass = eval(app.fictitiousMassEditField.Value);
            app.optimalBaseOptions.stiffnessFactor = eval(app.stiffnessFactorEditField.Value);
            app.optimalBaseOptions.gapPoints = eval(app.gapPointsEditField.Value);
            app.optimalBaseOptions.kNominal = eval(app.kNominalEditField_3.Value);
            [rigidModes, app.struData, app.model, app.struData_stiff, app.model_stiff, app.options, app.optimalBaseOptions] = ...
                obtainOptimalBase(app.inputData, app.optimalBaseOptions);
            app.ChoosethebasePanel.Visible = 'on';

            while(app.ConfirmchoiceButton.Value ~=1) 
                pause(1);
            end
            app.ConfirmchoiceButton.Value = 0;

            FMass = app.FictitiousmassEditField.Value;
            kFactor = app.StiffnessfactorEditField.Value;
            nModes = app.NumberofmodesEditField.Value;

            app.reducedBasis = defineBase(app.model, app.struData, rigidModes, app.options, app.optimalBaseOptions, nModes, kFactor, FMass);
            app.ConfirmchoiceButton.Text = 'Base ready';
            app.ConfirmchoiceButton.BackgroundColor = [0.65,0.69,0.79];
            app.NextButton_7.Visible = 'on';
            app.printDiary()
        end

        % Button pushed function: CreatethedatabaseButton
        function CreatethedatabaseButtonPushed(app, event)
            app.CreatethedatabaseButton.Text = 'Creating database';
            app.CreatethedatabaseButton.BackgroundColor = [0.65,0.69,0.79];
            app.aeroDatabaseOptions.useInApp = 1;
            app.aeroDatabaseOptions.meshType = app.ButtonGroup.SelectedObject.Text;
            app.aeroDatabaseOptions.noLinCurvature = app.CheckBox.Value;
            app.aeroDatabaseOptions.DynVLM = app.CheckBox_2.Value;
            app.aeroDatabaseOptions.DynVLMtype = app.ButtonGroup_2.SelectedObject.Text;
            if app.CheckBox_3.Value
                app.aeroDatabaseOptions.selectionTrim = 'all';
            else
                app.aeroDatabaseOptions.selectionTrim = app.getTrim(app.TrimlistEditField.Value);
            end
            [app.aeroData, app.aeroDatabaseOptions] = buildFullAeroData(app.model, app.model_stiff, app.struData_stiff, ...
                app.options, app.aeroDatabaseOptions);
            app.CreatethedatabaseButton.Text = 'Database ready';
            app.printDiary()
            app.NextButton_3.Visible = 'on';
        end

        % Button pushed function: PerformdescribingfunctionanalysesButton
        function PerformdescribingfunctionanalysesButtonPushed(app, event)
            app.PerformdescribingfunctionanalysesButton.BackgroundColor = [0.65,0.69,0.79];
            app.PerformdescribingfunctionanalysesButton.Text = 'Simulating...';
            app.describingFunctionOptions.useInApp = 1;
            app.describingFunctionOptions.gapPoints = app.optimalBaseOptions.gapPoints;
            app.describingFunctionOptions.gap = eval(app.GapEditField.Value);
            if isempty(app.NominalstiffnessEditField_2.Value)
                % Automatically gathers from optimal base
                app.describingFunctionOptions.kNominal = eval(app.kNominalEditField_3.Value);
                app.NominalstiffnessEditField_2.Value = app.stringFromCellArray(app.describingFunctionOptions.kNominal);
            else
                app.describingFunctionOptions.kNominal = eval(app.NominalstiffnessEditField_2.Value);
            end
            app.describingFunctionOptions.amplitudeDefinition = app.ButtonGroup_5.SelectedObject.Text;
            app.describingFunctionOptions.DynVLM = app.CheckBox_7.Value;
            app.describingFunctionOptions.DynVLMtype = app.ButtonGroup_6.SelectedObject.Text;
            app.describingFunctionOptions.selectionTrimDynVLM = app.SelectedtrimIDforVLMcorrectionEditField_3.Value;
            app.describingFunctionOptions.recomputeBase = app.CheckBox_5.Value;
            app.describingFunctionOptions.searchQuenchPoint = app.CheckBox_6.Value;
            app.describingFunctionOptions.maxKeq = app.maxKeqEditField.Value;
            app.describingFunctionOptions.nKeq = app.nKeqEditField.Value;
            app.describingFunctionOptions.Vmax = app.VmaxEditField.Value;
            app.describingFunctionOptions.Vmin = app.VminEditField.Value;
            app.describingFunctionOptions.Vstep = app.VstepEditField.Value;
            app.describingFunctionOptions.method = app.ButtonGroup_4.SelectedObject.Text;
            app.describingFunctionOptions.rho = app.DensityEditField.Value;
            app.describingFunctionOptions.machNumber = app.MachnumberEditField.Value;
            app.describingFunctionOptions.modesPlot = eval(app.ModestoplotinVgfdiagramsEditField.Value);
            app.describingFunctionOptions.axesUsed = app.ButtonGroup_7.SelectedObject.Text;
            app.describingFunctionOptions.introduceFlightLoads = app.CheckBox_4.Value;
            app.describingFunctionOptions.introduceStruLoads = app.CheckBox_19.Value;
            app.describingFunctionOptions.introduceGravityLoads = app.CheckBox_20.Value;
            app.describingFunctionOptions.trimType = app.ButtonGroup_3.SelectedObject.Text;
            app.describingFunctionOptions.selectionTrim = app.SelectedtrimIDforloadsEditField_3.Value;
            [app.describingFunctionResults, app.describingFunctionOptions] = describingFunctions(app.model, app.struData, app.aeroData, ...
                app.options, app.reducedBasis, app.aeroDatabaseOptions, app.describingFunctionOptions);
            app.PerformdescribingfunctionanalysesButton.Text = 'Simulation completed';
            app.printDiary()
            app.NextButton_4.Visible = 'on';
        end

        % Button pushed function: GatherfromoptimalbaseoptionsButton
        function GatherfromoptimalbaseoptionsButtonPushed(app, event)
            app.describingFunctionOptions.kNominal = eval(app.kNominalEditField_3.Value);
            app.NominalstiffnessEditField_2.Value = app.stringFromCellArray(app.describingFunctionOptions.kNominal);
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.CreatemodalbaseTab;
            app.NextButton.BackgroundColor = [0.65,0.69,0.79];
        end

        % Button pushed function: NextButton_3
        function NextButton_3Pushed(app, event)
            app.TabGroup.SelectedTab = app.DescribingfunctionanalysisTab;
            app.NextButton_3.BackgroundColor = [0.65,0.69,0.79];
        end

        % Button pushed function: NextButton_4
        function NextButton_4Pushed(app, event)
            app.TabGroup.SelectedTab = app.TimemarchinganalysisTab;
            app.TabGroup2.SelectedTab = app.PreprocesssimulationTab;
            app.NextButton_4.BackgroundColor = [0.65,0.69,0.79];
        end

        % Button pushed function: NextButton_5
        function NextButton_5Pushed(app, event)
            app.TabGroup.SelectedTab = app.MainplotsTab;
            app.NextButton_5.BackgroundColor = [0.65,0.69,0.79];
        end

        % Button pushed function: PerformtimemarchinganalysesButton
        function PerformtimemarchinganalysesButtonPushed(app, event)
            app.PerformtimemarchinganalysesButton.Text = 'Simulating...';
            app.PerformtimemarchinganalysesButton.BackgroundColor = [0.65,0.69,0.79];
            app.timeMarchingOptions.useInApp = 1;
            app.timeMarchingOptions.nProcessors = app.NumberofprocessorsEditField.Value;
            app.timeMarchingOptions.rho = app.DensityEditField_2.Value;
            app.timeMarchingOptions.speedVector = eval(app.SpeedvectorEditField.Value);
            app.timeMarchingOptions.speedBehaviour = app.ButtonGroup_9.SelectedObject.Text;
            app.timeMarchingOptions.speedInterpolationType = app.selectType(app.ButtonGroup_10.SelectedObject.Text);
            if isempty(app.NominalstiffnessEditField.Value)
                app.timeMarchingOptions.kNominal = app.optimalBaseOptions.kNominal;
                app.NominalstiffnessEditField.Value = app.stringFromCellArray(app.timeMarchingOptions.kNominal);
            else
                app.timeMarchingOptions.kNominal = eval(app.NominalstiffnessEditField.Value);
            end
            if isempty(app.GapdimensionsEditField.Value)
                app.timeMarchingOptions.gap = eval(app.GapEditField.Value);
                app.GapdimensionsEditField.Value = app.stringFromCellArray(app.timeMarchingOptions.gap);
            else
                app.timeMarchingOptions.gap = eval(app.GapdimensionsEditField.Value);
            end
            if isempty(app.EditField.Value)
                app.timeMarchingOptions.gapInterpolationType = repmat(app.selectType(app.ButtonGroup_11.SelectedObject.Text)...
                    ,1,length(app.timeMarchingOptions.gap));
                app.EditField.Value = strcat("[",num2str(app.timeMarchingOptions.gapInterpolationType),"]");
            else
                app.timeMarchingOptions.gapInterpolationType = eval(app.EditField.Value);
            end
            app.timeMarchingOptions.initialAmplitude = app.InitialamplitudefactorEditField.Value;
            app.timeMarchingOptions.initialisationTime = app.InitialisationtimeEditField.Value;
            app.timeMarchingOptions.nFFTwindows = app.NumberofFFTwindowsEditField.Value;
            app.timeMarchingOptions.FFTwindowLength = app.FFTwindowlengthEditField.Value;
            app.timeMarchingOptions.timeStepForFFT = app.timestepforFFTEditField.Value;
            app.timeMarchingOptions.overlapWindows = app.OverlapofFFTwindowsEditField.Value;
            app.timeMarchingOptions.maxFrequencyInterest = app.MaxfrequencyofinterestEditField.Value;
            app.timeMarchingOptions.amplitudeDefinition = app.ButtonGroup_12.SelectedObject.Text;
            app.timeMarchingOptions.plotMaxRealEigSS = app.CheckBox_10.Value;
            app.timeMarchingOptions.plotFixedPoints = app.CheckBox_11.Value;
            app.timeMarchingOptions.introduceFlightLoads = app.CheckBox_12.Value;
            app.timeMarchingOptions.trimType = app.ButtonGroup_13.SelectedObject.Text;
            app.timeMarchingOptions.selectionTrim = app.SelectedtrimIDforloadsEditField.Value;
            app.timeMarchingOptions.introduceStruLoads = app.CheckBox_13.Value;
            if isempty(app.StructuralloadsDefinedEditField.Value)
                app.timeMarchingOptions.struLoads = {};
            else
                app.timeMarchingOptions.struLoads = eval(app.StructuralloadsDefinedEditField.Value);
            end
            app.timeMarchingOptions.introduceGravityLoads = app.CheckBox_18.Value;
            if isempty(app.GravitydirectionEditField.Value)
                app.timeMarchingOptions.gravDirection = [];
            else
                app.timeMarchingOptions.gravDirection = eval(app.GravitydirectionEditField.Value);
            end
            [app.timeMarchingResults, app.timeMarchingOptions]=timeMarchingLCO(app.modelForIntegration, app.timeMarchingOptions, ...
                app.preprocessTimeMarchingOptions);
            app.PerformtimemarchinganalysesButton.Text = 'Simulation completed';
            app.printDiary()
            app.NextButton_5.Visible = 'on';
        end

        % Button pushed function: NextButton_6
        function NextButton_6Pushed(app, event)
            app.TabGroup2.SelectedTab = app.PerformanalysesTab;
            app.NextButton_6.BackgroundColor = [0.65,0.69,0.79];
        end

        % Button pushed function: CreatetimemarchingmodelButton
        function CreatetimemarchingmodelButtonPushed(app, event)
            app.CreatetimemarchingmodelButton.Text = 'Creating simulink model';
            app.CreatetimemarchingmodelButton.BackgroundColor = [0.65,0.69,0.79];
            app.preprocessTimeMarchingOptions.useInApp = 1;
            app.preprocessTimeMarchingOptions.gapPoints = app.optimalBaseOptions.gapPoints;
            app.preprocessTimeMarchingOptions.gapBehaviour = eval(app.GapBehaviourEditField.Value);
            if isempty(app.MonitorPointsEditField.Value)
                app.preprocessTimeMarchingOptions.monitorPoints = {};
            else
                app.preprocessTimeMarchingOptions.monitorPoints = eval(app.MonitorPointsEditField.Value);
            end
            app.preprocessTimeMarchingOptions.DynVLM = app.CheckBox_8.Value;
            app.preprocessTimeMarchingOptions.DynVLMtype = app.ButtonGroup_8.SelectedObject.Text;
            app.preprocessTimeMarchingOptions.selectionTrim = app.SelectedtrimIDforVLMcorrectionEditField_4.Value;
            app.preprocessTimeMarchingOptions.checkStateSpaceApproximation = app.CheckBox_9.Value;
            app.preprocessTimeMarchingOptions.machNumber = app.MachnumberEditField_2.Value;
            if isempty(app.ChosenmodesEditField.Value)
                app.preprocessTimeMarchingOptions.chosenModes = [];
            else
                app.preprocessTimeMarchingOptions.chosenModes = eval(app.ChosenmodesEditField.Value);
            end
            if app.ROMorderEditField.Value == 0
                app.preprocessTimeMarchingOptions.orderROM = [];
            else
                app.preprocessTimeMarchingOptions.orderROM = app.ROMorderEditField.Value;
            end
            [app.modelForIntegration, app.preprocessTimeMarchingOptions] = preprocessTimeMarchingLCO(app.model, app.struData, ...
                app.preprocessTimeMarchingOptions, app.reducedBasis, app.aeroData, app.options);
            app.ChosenmodesEditField.Value = strcat("[",num2str(app.preprocessTimeMarchingOptions.chosenModes),"]");
            app.CreatetimemarchingmodelButton.Text = 'Model ready';
            app.printDiary()
            app.NextButton_6.Visible = 'on';
        end

        % Button pushed function: LaunchButton
        function LaunchButtonPushed(app, event)
            app.LaunchButton.BackgroundColor = [0.65,0.69,0.79];
            app.NextButtonPushed()
            app.ConfirmchoiceButton.Value = 1;
            app.ComputebaseButtonPushed()
            app.NextButton_7Pushed()
            app.CreatethedatabaseButtonPushed()
            app.NextButton_3Pushed()
            app.PerformdescribingfunctionanalysesButtonPushed()
            app.NextButton_4Pushed()
            app.CreatetimemarchingmodelButtonPushed()
            app.NextButton_6Pushed()
            app.PerformtimemarchinganalysesButtonPushed()
            app.NextButton_5Pushed()
            app.ComputemainoutputsButtonPushed()
        end

        % Button pushed function: NextButton_7
        function NextButton_7Pushed(app, event)
            app.TabGroup.SelectedTab = app.CreateaerodynamicdatabaseTab;
            app.NextButton_7.BackgroundColor = [0.65,0.69,0.79];
        end

        % Button pushed function: Performallanalyses
        function PerformallanalysesButtonPushed(app, event)
            app.PerformdescribingfunctionanalysesButtonPushed()
            app.NextButton_4Pushed()
            app.CreatetimemarchingmodelButtonPushed()
            app.NextButton_6Pushed()
            app.PerformtimemarchinganalysesButtonPushed()
            app.NextButton_5Pushed()
            app.ComputemainoutputsButtonPushed()
        end

        % Button pushed function: gatherfromdescribingfunctionoptionsButton
        function gatherfromdescribingfunctionoptionsButtonPushed(app, event)
            app.timeMarchingOptions.kNominal = app.optimalBaseOptions.kNominal;
            app.NominalstiffnessEditField.Value = app.stringFromCellArray(app.timeMarchingOptions.kNominal);
            app.timeMarchingOptions.gap = eval(app.GapEditField.Value);
            app.GapdimensionsEditField.Value = app.stringFromCellArray(app.timeMarchingOptions.gap);
        end

        % Button pushed function: ComputemainoutputsButton
        function ComputemainoutputsButtonPushed(app, event)
            app.ComputemainoutputsButton.Text = 'Plotting';
            app.ComputemainoutputsButton.BackgroundColor = [0.65,0.69,0.79];
            app.plotOptions.useInApp = 1;
            app.plotOptions.halfGapNormalisation = app.CheckBox_14.Value;
            app.plotOptions.singleGapDF = app.CheckBox_15.Value;
            app.plotOptions.normalisationSpeed = app.ValueforwindspeednormalisationEditField.Value;
            app.plotOptions.monitorNormalisation = app.CheckBox_16.Value;
            app.plotOptions.torqueNormalisation = app.CheckBox_17.Value;
            app.plotOptions.plotType = app.ButtonGroup_14.SelectedObject.Text;
            app.plotOptions.plotForApp = app.NonlinearitytobeusedEditField.Value;
            mainPlotRoutine(app.describingFunctionResults, app.describingFunctionOptions, ...
                app.timeMarchingResults, app.timeMarchingOptions, app.preprocessTimeMarchingOptions, app.plotOptions)
            app.ComputemainoutputsButton.Text = 'Plotting completed';
            app.printDiary()
            app.ExitButton.Visible = 'on';
            app.ExitsavingconfigurationButton.Visible = 'on';
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            close all
            app.saveData()
            app.delete()
        end

        % Button pushed function: ExitsavingconfigurationButton
        function ExitsavingconfigurationButtonPushed(app, event)
            app.dumpConfig()
            close all
            app.saveData()
            app.delete()
        end

        % Value changed function: 
        % ordirectlyspecifythefilefullpathEditField_2
        function ordirectlyspecifythefilefullpathEditField_2ValueChanged(app, event)
            app.parseConfig()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.NeoLCOUIFigure = uifigure('Visible', 'off');
            app.NeoLCOUIFigure.Name = 'NeoLCO';
            app.TabGroup = uitabgroup(app.NeoLCOUIFigure);
            app.ImportfileTab = uitab(app.TabGroup);
            app.ImportfileTab.Title = '1-Import file';
            app.ImporttheFEMmodelPanel = uipanel(app.ImportfileTab);
            app.ImporttheFEMmodelPanel.Title = '1.1-Import the FEM model';
            app.LoadmodelfilefromguiButton = uibutton(app.ImporttheFEMmodelPanel, 'push');
            app.LoadmodelfilefromguiButton.ButtonPushedFcn = createCallbackFcn(app, @LoadmodelfilefromguiButtonPushed, true);
            app.LoadmodelfilefromguiButton.Text = 'Load model file from gui';
            app.LoadaFEMmodelforsubsequentanalysisLabel = uilabel(app.ImporttheFEMmodelPanel);
            app.LoadaFEMmodelforsubsequentanalysisLabel.Text = 'Load a FEM model for subsequent analysis';
            app.VerifyFEMmodelButton = uibutton(app.ImporttheFEMmodelPanel, 'push');
            app.VerifyFEMmodelButton.ButtonPushedFcn = createCallbackFcn(app, @VerifyFEMmodelButtonPushed, true);
            app.VerifyFEMmodelButton.Text = 'Verify FEM model';
            app.ordirectlyspecifythefilefullpathEditFieldLabel = uilabel(app.ImporttheFEMmodelPanel);
            app.ordirectlyspecifythefilefullpathEditFieldLabel.Text = 'or directly specify the file full path';
            app.ordirectlyspecifythefilefullpathEditField = uieditfield(app.ImporttheFEMmodelPanel, 'text');
            app.ordirectlyspecifythefilefullpathEditField.Value = '/home/nicolafonzi/test2/flutter.dat';
            app.ImportaconfigurationfileoptionalPanel = uipanel(app.ImportfileTab);
            app.ImportaconfigurationfileoptionalPanel.Title = '1.2-Import a configuration file (optional)';
            app.LoadconfigurationfileButton = uibutton(app.ImportaconfigurationfileoptionalPanel, 'push');
            app.LoadconfigurationfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadconfigurationfileButtonPushed, true);
            app.LoadconfigurationfileButton.Text = 'Load configuration file';
            app.NextButton = uibutton(app.ImportaconfigurationfileoptionalPanel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.Label_2 = uilabel(app.ImportaconfigurationfileoptionalPanel);
            app.Label_2.Text = 'Proceed with next to manually configure the software behaviour, or upload your configuration file and perform all operations up to step 6';
            app.LaunchButton = uibutton(app.ImportaconfigurationfileoptionalPanel, 'push');
            app.LaunchButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchButtonPushed, true);
            app.LaunchButton.Text = 'Launch';
            app.ordirectlyspecifythefilefullpathEditField_2Label = uilabel(app.ImportaconfigurationfileoptionalPanel);
            app.ordirectlyspecifythefilefullpathEditField_2Label.Text = 'or directly specify the file full path';
            app.ordirectlyspecifythefilefullpathEditField_2 = uieditfield(app.ImportaconfigurationfileoptionalPanel, 'text');
            app.ordirectlyspecifythefilefullpathEditField_2.ValueChangedFcn = createCallbackFcn(app, @ordirectlyspecifythefilefullpathEditField_2ValueChanged, true);
            app.Panel = uipanel(app.ImportfileTab);
            app.CreatemodalbaseTab = uitab(app.TabGroup);
            app.CreatemodalbaseTab.Title = '2-Create modal base';
            app.DefinesearchparametersfortheoptimalbasePanel = uipanel(app.CreatemodalbaseTab);
            app.DefinesearchparametersfortheoptimalbasePanel.Title = '2.1-Define search parameters for the optimal base';
            app.labelgapPoints = uilabel(app.DefinesearchparametersfortheoptimalbasePanel);
            app.labelgapPoints.Text = {'Points where nonlinearities are present. The format is {point1,"s" or "g",dof (1,2,3,4,5 or 6),label;point2,...}.'; 'If in the same point we have more nonlinearity, the point must be repeated per each dof.'};
            app.labelkNominal = uilabel(app.DefinesearchparametersfortheoptimalbasePanel);
            app.labelkNominal.Text = {'Cells array containing the nominal stiffnesses at the nonlinearity points. The rows are in the same order as gapPoint IDs. '; 'The columns contain possible different values for the same point. '; 'The format is {[k1_point1,k2_point1];[k1_point2,k2_point2,k3_point2]}.'};
            app.labelReferenceNModes = uilabel(app.DefinesearchparametersfortheoptimalbasePanel);
            app.labelReferenceNModes.Text = 'Number of modes to be retained and used as a reference for the "correct" system.';
            app.OptimumknownCheckBox = uicheckbox(app.DefinesearchparametersfortheoptimalbasePanel);
            app.OptimumknownCheckBox.Text = '        Optimum known';
            app.OptimumknownCheckBox.Value = true;
            app.labelOptimumKnown = uilabel(app.DefinesearchparametersfortheoptimalbasePanel);
            app.labelOptimumKnown.Text = 'Option that can be used in case the optimum base is known.';
            app.VectorofmodestobetestedLabel = uilabel(app.DefinesearchparametersfortheoptimalbasePanel);
            app.VectorofmodestobetestedLabel.Text = 'Vector of modes to be tested.';
            app.VectoroffactorsthatmultipliesthenominalstiffnesstobetestedLabel = uilabel(app.DefinesearchparametersfortheoptimalbasePanel);
            app.VectoroffactorsthatmultipliesthenominalstiffnesstobetestedLabel.Text = 'Vector of factors that multiplies the nominal stiffness to be tested.';
            app.labelFictitiousMass = uilabel(app.DefinesearchparametersfortheoptimalbasePanel);
            app.labelFictitiousMass.Text = 'Vector of fictitious masses to be tested.';
            app.labelMaxFreqInterest = uilabel(app.DefinesearchparametersfortheoptimalbasePanel);
            app.labelMaxFreqInterest.Text = 'Max frequency that is of interest. Inside this band the error will be computed.';
            app.ComputebaseButton = uibutton(app.DefinesearchparametersfortheoptimalbasePanel, 'push');
            app.ComputebaseButton.ButtonPushedFcn = createCallbackFcn(app, @ComputebaseButtonPushed, true);
            app.ComputebaseButton.Text = 'Compute base';
            app.gapPointsEditFieldLabel = uilabel(app.DefinesearchparametersfortheoptimalbasePanel);
            app.gapPointsEditFieldLabel.Text = 'gapPoints';
            app.gapPointsEditField = uieditfield(app.DefinesearchparametersfortheoptimalbasePanel, 'text');
            app.gapPointsEditField.Value = '{1,''s'',0,''Elevator hinge''}';
            app.kNominalEditField_3Label = uilabel(app.DefinesearchparametersfortheoptimalbasePanel);
            app.kNominalEditField_3Label.Text = 'kNominal';
            app.kNominalEditField_3 = uieditfield(app.DefinesearchparametersfortheoptimalbasePanel, 'text');
            app.kNominalEditField_3.Value = '{48}';
            app.referenceNModesEditFieldLabel = uilabel(app.DefinesearchparametersfortheoptimalbasePanel);
            app.referenceNModesEditFieldLabel.Text = 'referenceNModes';
            app.referenceNModesEditField = uieditfield(app.DefinesearchparametersfortheoptimalbasePanel, 'numeric');
            app.referenceNModesEditField.Value = 100;
            app.nModesEditFieldLabel = uilabel(app.DefinesearchparametersfortheoptimalbasePanel);
            app.nModesEditFieldLabel.Text = 'nModes';
            app.nModesEditField = uieditfield(app.DefinesearchparametersfortheoptimalbasePanel, 'text');
            app.nModesEditField.Value = '[8 12 16 20]';
            app.stiffnessFactorEditFieldLabel = uilabel(app.DefinesearchparametersfortheoptimalbasePanel);
            app.stiffnessFactorEditFieldLabel.Text = 'stiffnessFactor';
            app.stiffnessFactorEditField = uieditfield(app.DefinesearchparametersfortheoptimalbasePanel, 'text');
            app.stiffnessFactorEditField.Value = '[1e-4 1e-2 1e0 1e2 1e4]';
            app.fictitiousMassEditFieldLabel = uilabel(app.DefinesearchparametersfortheoptimalbasePanel);
            app.fictitiousMassEditFieldLabel.Text = 'fictitiousMass';
            app.fictitiousMassEditField = uieditfield(app.DefinesearchparametersfortheoptimalbasePanel, 'text');
            app.fictitiousMassEditField.Value = '[0.00001 0.001 0.1 0.5 1]';
            app.maxFreqInterestEditFieldLabel = uilabel(app.DefinesearchparametersfortheoptimalbasePanel);
            app.maxFreqInterestEditFieldLabel.Text = 'maxFreqInterest';
            app.maxFreqInterestEditField = uieditfield(app.DefinesearchparametersfortheoptimalbasePanel, 'numeric');
            app.maxFreqInterestEditField.Value = 100;
            app.ChoosethebasePanel = uipanel(app.DefinesearchparametersfortheoptimalbasePanel);
            app.ChoosethebasePanel.Title = '2.2-Choose the base';
            app.ConfirmchoiceButton = uibutton(app.ChoosethebasePanel, 'state');
            app.ConfirmchoiceButton.Text = 'Confirm choice';
            app.NumberofmodesEditFieldLabel = uilabel(app.ChoosethebasePanel);
            app.NumberofmodesEditFieldLabel.Text = 'Number of modes';
            app.NumberofmodesEditField = uieditfield(app.ChoosethebasePanel, 'numeric');
            app.NumberofmodesEditField.Value = 12;
            app.StiffnessfactorEditFieldLabel = uilabel(app.ChoosethebasePanel);
            app.StiffnessfactorEditFieldLabel.Text = 'Stiffness factor';
            app.StiffnessfactorEditField = uieditfield(app.ChoosethebasePanel, 'numeric');
            app.StiffnessfactorEditField.Value = 1;
            app.FictitiousmassEditFieldLabel = uilabel(app.ChoosethebasePanel);
            app.FictitiousmassEditFieldLabel.Text = 'Fictitious mass';
            app.FictitiousmassEditField = uieditfield(app.ChoosethebasePanel, 'numeric');
            app.NextButton_7 = uibutton(app.ChoosethebasePanel, 'push');
            app.NextButton_7.ButtonPushedFcn = createCallbackFcn(app, @NextButton_7Pushed, true);
            app.NextButton_7.Text = 'Next';
            app.CreateaerodynamicdatabaseTab = uitab(app.TabGroup);
            app.CreateaerodynamicdatabaseTab.Title = '3-Create aerodynamic database';
            app.TheflatmeshtypealsoconsiderstheLabel = uilabel(app.CreateaerodynamicdatabaseTab);
            app.TheflatmeshtypealsoconsiderstheLabel.Text = 'The flat mesh type also considers the profile using the variation of normals';
            app.ButtonGroup = uibuttongroup(app.CreateaerodynamicdatabaseTab);
            app.flatButton = uiradiobutton(app.ButtonGroup);
            app.flatButton.Text = 'flat';
            app.flatButton.Value = true;
            app.fullflatButton = uiradiobutton(app.ButtonGroup);
            app.fullflatButton.Text = 'fullflat';
            app.CheckBox = uicheckbox(app.CreateaerodynamicdatabaseTab);
            app.CheckBox.Text = '';
            app.CheckBox_2 = uicheckbox(app.CreateaerodynamicdatabaseTab);
            app.CheckBox_2.Text = '';
            app.ButtonGroup_2 = uibuttongroup(app.CreateaerodynamicdatabaseTab);
            app.steadyButton = uiradiobutton(app.ButtonGroup_2);
            app.steadyButton.Text = 'steady';
            app.quasiSteadyButton = uiradiobutton(app.ButtonGroup_2);
            app.quasiSteadyButton.Text = 'quasiSteady';
            app.unsteadyButton = uiradiobutton(app.ButtonGroup_2);
            app.unsteadyButton.Text = 'unsteady';
            app.unsteadyButton.Value = true;
            app.CheckBox_3 = uicheckbox(app.CreateaerodynamicdatabaseTab);
            app.CheckBox_3.Text = '';
            app.CheckBox_3.Value = true;
            app.labelFlagToAvoidLinCurvature = uilabel(app.CreateaerodynamicdatabaseTab);
            app.labelFlagToAvoidLinCurvature.Text = 'Flag to avoid the use of the curvature treatment';
            app.FlagtoactivatetheuseofthecorrectedDLMLabel = uilabel(app.CreateaerodynamicdatabaseTab);
            app.FlagtoactivatetheuseofthecorrectedDLMLabel.Text = 'Flag to activate the use of the corrected DLM';
            app.OptionusedtodefinethetypeofcorrectionLabel = uilabel(app.CreateaerodynamicdatabaseTab);
            app.OptionusedtodefinethetypeofcorrectionLabel.Text = 'Option used to define the type of correction';
            app.OptionusedtoselectthetrimstobeusedfortheDLMcorrectionLabel = uilabel(app.CreateaerodynamicdatabaseTab);
            app.OptionusedtoselectthetrimstobeusedfortheDLMcorrectionLabel.Text = {'Option used to select the trims to be used for the DLM correction.'; 'If all is selected, the previous list is discarted.'};
            app.CreatethedatabaseButton = uibutton(app.CreateaerodynamicdatabaseTab, 'push');
            app.CreatethedatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @CreatethedatabaseButtonPushed, true);
            app.CreatethedatabaseButton.Text = 'Create the database';
            app.TrimlistEditFieldLabel = uilabel(app.CreateaerodynamicdatabaseTab);
            app.TrimlistEditFieldLabel.Text = 'Trim list';
            app.TrimlistEditField = uieditfield(app.CreateaerodynamicdatabaseTab, 'text');
            app.TrimlistEditField.Value = '[1,2,3,4]';
            app.NextButton_3 = uibutton(app.CreateaerodynamicdatabaseTab, 'push');
            app.NextButton_3.ButtonPushedFcn = createCallbackFcn(app, @NextButton_3Pushed, true);
            app.NextButton_3.Text = 'Next';
            app.meshTypeLabel = uilabel(app.CreateaerodynamicdatabaseTab);
            app.meshTypeLabel.Text = 'meshType';
            app.dynVLMtypeLabel = uilabel(app.CreateaerodynamicdatabaseTab);
            app.dynVLMtypeLabel.Text = 'dynVLMtype';
            app.noLinCurvatureLabel = uilabel(app.CreateaerodynamicdatabaseTab);
            app.noLinCurvatureLabel.Text = 'noLinCurvature';
            app.dynVLMLabel = uilabel(app.CreateaerodynamicdatabaseTab);
            app.dynVLMLabel.Text = 'dynVLM';
            app.AllLabel = uilabel(app.CreateaerodynamicdatabaseTab);
            app.AllLabel.Text = 'All';
            app.DescribingfunctionanalysisTab = uitab(app.TabGroup);
            app.DescribingfunctionanalysisTab.Title = '4-Describing function analysis';
            app.Label_4 = uilabel(app.DescribingfunctionanalysisTab);
            app.Label_4.Text = '';
            app.GlobalparmetersPanel = uipanel(app.DescribingfunctionanalysisTab);
            app.GlobalparmetersPanel.Title = '4.1-Global parmeters';
            app.Label_3 = uilabel(app.GlobalparmetersPanel);
            app.Label_3.Text = '';
            app.CheckBox_4 = uicheckbox(app.GlobalparmetersPanel);
            app.CheckBox_4.Text = '';
            app.CheckBox_4.Value = true;
            app.ButtonGroup_3 = uibuttongroup(app.GlobalparmetersPanel);
            app.rigidButton = uiradiobutton(app.ButtonGroup_3);
            app.rigidButton.Text = 'rigid';
            app.meanAxesButton = uiradiobutton(app.ButtonGroup_3);
            app.meanAxesButton.Text = 'meanAxes';
            app.groundedButton = uiradiobutton(app.ButtonGroup_3);
            app.groundedButton.Text = 'grounded';
            app.groundedButton.Value = true;
            app.restrainedButton = uiradiobutton(app.ButtonGroup_3);
            app.restrainedButton.Text = 'restrained';
            app.CheckBox_5 = uicheckbox(app.GlobalparmetersPanel);
            app.CheckBox_5.Text = '';
            app.CheckBox_6 = uicheckbox(app.GlobalparmetersPanel);
            app.CheckBox_6.Text = '';
            app.CheckBox_6.Value = true;
            app.ButtonGroup_4 = uibuttongroup(app.GlobalparmetersPanel);
            app.PK0Button = uiradiobutton(app.ButtonGroup_4);
            app.PK0Button.Text = 'PK0';
            app.PK0Button.Value = true;
            app.PKButton = uiradiobutton(app.ButtonGroup_4);
            app.PKButton.Text = 'PK';
            app.maxKeqEditFieldLabel = uilabel(app.GlobalparmetersPanel);
            app.maxKeqEditFieldLabel.Text = 'max Keq';
            app.maxKeqEditField = uieditfield(app.GlobalparmetersPanel, 'numeric');
            app.maxKeqEditField.Value = 4.8;
            app.nKeqEditFieldLabel = uilabel(app.GlobalparmetersPanel);
            app.nKeqEditFieldLabel.Text = 'nKeq';
            app.nKeqEditField = uieditfield(app.GlobalparmetersPanel, 'numeric');
            app.nKeqEditField.Value = 50;
            app.VmaxEditFieldLabel = uilabel(app.GlobalparmetersPanel);
            app.VmaxEditFieldLabel.Text = 'Vmax';
            app.VmaxEditField = uieditfield(app.GlobalparmetersPanel, 'numeric');
            app.VmaxEditField.Value = 70;
            app.VminEditFieldLabel = uilabel(app.GlobalparmetersPanel);
            app.VminEditFieldLabel.Text = 'Vmin';
            app.VminEditField = uieditfield(app.GlobalparmetersPanel, 'numeric');
            app.VminEditField.Value = 5;
            app.VstepEditFieldLabel = uilabel(app.GlobalparmetersPanel);
            app.VstepEditFieldLabel.Text = 'Vstep';
            app.VstepEditField = uieditfield(app.GlobalparmetersPanel, 'numeric');
            app.VstepEditField.Value = 1;
            app.IntroduceflightloadsLabel = uilabel(app.GlobalparmetersPanel);
            app.IntroduceflightloadsLabel.Text = 'Introduce flight loads';
            app.RecomputebaseLabel = uilabel(app.GlobalparmetersPanel);
            app.RecomputebaseLabel.Text = 'Recompute base';
            app.TrimtypeLabel = uilabel(app.GlobalparmetersPanel);
            app.TrimtypeLabel.Text = 'Trim type';
            app.SearchquenchpointLabel = uilabel(app.GlobalparmetersPanel);
            app.SearchquenchpointLabel.Text = 'Search quench point';
            app.FluttersearchmethodLabel = uilabel(app.GlobalparmetersPanel);
            app.FluttersearchmethodLabel.Text = 'Flutter search method';
            app.SelectedtrimIDforloadsEditField_3Label = uilabel(app.GlobalparmetersPanel);
            app.SelectedtrimIDforloadsEditField_3Label.Text = {'Selected trim'; 'ID for loads'};
            app.SelectedtrimIDforloadsEditField_3 = uieditfield(app.GlobalparmetersPanel, 'numeric');
            app.SelectedtrimIDforloadsEditField_3.Value = 1;
            app.CheckBox_19 = uicheckbox(app.GlobalparmetersPanel);
            app.CheckBox_19.Text = '';
            app.CheckBox_20 = uicheckbox(app.GlobalparmetersPanel);
            app.CheckBox_20.Text = '';
            app.StructuralloadsEditFieldLabel = uilabel(app.GlobalparmetersPanel);
            app.StructuralloadsEditFieldLabel.Text = 'Structural loads. ';
            app.StructuralloadsEditField = uieditfield(app.GlobalparmetersPanel, 'text');
            app.StructuralloadsEditField.Value = '{}';
            app.GravitydirectionEditField_2Label = uilabel(app.GlobalparmetersPanel);
            app.GravitydirectionEditField_2Label.Text = 'Gravity direction';
            app.GravitydirectionEditField_2 = uieditfield(app.GlobalparmetersPanel, 'text');
            app.GravitydirectionEditField_2.Value = '[]';
            app.IntroducestructuralloadsLabel_2 = uilabel(app.GlobalparmetersPanel);
            app.IntroducestructuralloadsLabel_2.Text = 'Introduce structural loads';
            app.Theformatispoint1sorgdof12345or6loadpoint2Label_2 = uilabel(app.GlobalparmetersPanel);
            app.Theformatispoint1sorgdof12345or6loadpoint2Label_2.Text = {'The format is {point1, "s" or "g", '; 'dof (1,2,3,4,5 or 6), load;point2,...}'};
            app.IntroducegravityloadsLabel_2 = uilabel(app.GlobalparmetersPanel);
            app.IntroducegravityloadsLabel_2.Text = 'Introduce gravity loads';
            app.StructuralparametersPanel = uipanel(app.DescribingfunctionanalysisTab);
            app.StructuralparametersPanel.Title = '4.2-Structural parameters';
            app.GapEditField_2Label = uilabel(app.StructuralparametersPanel);
            app.GapEditField_2Label.Text = 'Gap';
            app.GapEditField = uieditfield(app.StructuralparametersPanel, 'text');
            app.GapEditField.Value = '{[0.1, 0.2, 0.4, 0.6, 1, 1.5, 2, 2.5, 3]/180*pi}';
            app.GatherfromoptimalbaseoptionsButton = uibutton(app.StructuralparametersPanel, 'push');
            app.GatherfromoptimalbaseoptionsButton.ButtonPushedFcn = createCallbackFcn(app, @GatherfromoptimalbaseoptionsButtonPushed, true);
            app.GatherfromoptimalbaseoptionsButton.Text = 'Gather from optimal base options';
            app.NominalstiffnessEditField_2Label = uilabel(app.StructuralparametersPanel);
            app.NominalstiffnessEditField_2Label.Text = 'Nominal stiffness';
            app.NominalstiffnessEditField_2 = uieditfield(app.StructuralparametersPanel, 'text');
            app.ButtonGroup_5 = uibuttongroup(app.StructuralparametersPanel);
            app.maxPeakButton_2 = uiradiobutton(app.ButtonGroup_5);
            app.maxPeakButton_2.Text = 'maxPeak';
            app.rmsButton = uiradiobutton(app.ButtonGroup_5);
            app.rmsButton.Text = 'rms';
            app.stdButton = uiradiobutton(app.ButtonGroup_5);
            app.stdButton.Text = 'std';
            app.stdButton.Value = true;
            app.ModestoplotinVgfdiagramsEditFieldLabel = uilabel(app.StructuralparametersPanel);
            app.ModestoplotinVgfdiagramsEditFieldLabel.Text = 'Modes to plot in V-g-f diagrams';
            app.ModestoplotinVgfdiagramsEditField = uieditfield(app.StructuralparametersPanel, 'text');
            app.ModestoplotinVgfdiagramsEditField.Value = '1:8';
            app.AmplitudedefinitionLabel = uilabel(app.StructuralparametersPanel);
            app.AmplitudedefinitionLabel.Text = 'Amplitude definition';
            app.LaunchdescribingfunctionsimulationPanel = uipanel(app.DescribingfunctionanalysisTab);
            app.LaunchdescribingfunctionsimulationPanel.Title = '4.4-Launch describing function simulation';
            app.PerformdescribingfunctionanalysesButton = uibutton(app.LaunchdescribingfunctionsimulationPanel, 'push');
            app.PerformdescribingfunctionanalysesButton.ButtonPushedFcn = createCallbackFcn(app, @PerformdescribingfunctionanalysesButtonPushed, true);
            app.PerformdescribingfunctionanalysesButton.Text = 'Perform describing function analyses';
            app.NextButton_4 = uibutton(app.LaunchdescribingfunctionsimulationPanel, 'push');
            app.NextButton_4.ButtonPushedFcn = createCallbackFcn(app, @NextButton_4Pushed, true);
            app.NextButton_4.Text = 'Next';
            app.Performallanalyses = uibutton(app.LaunchdescribingfunctionsimulationPanel, 'push');
            app.Performallanalyses.ButtonPushedFcn = createCallbackFcn(app, @PerformallanalysesButtonPushed, true);
            app.Performallanalyses.Text = 'Perform all analysis up to step 6';
            app.PleasechecktheinputsalsointhefollowingtabsLabel = uilabel(app.LaunchdescribingfunctionsimulationPanel);
            app.PleasechecktheinputsalsointhefollowingtabsLabel.Text = 'Please check the inputs also in the following tabs';
            app.Label_5 = uilabel(app.LaunchdescribingfunctionsimulationPanel);
            app.Label_5.Text = 'Simulate using the describing function approach only or automatically call also the time marching approach';
            app.AerodynamicparametersPanel = uipanel(app.DescribingfunctionanalysisTab);
            app.AerodynamicparametersPanel.Title = '4.3-Aerodynamic parameters';
            app.CheckBox_7 = uicheckbox(app.AerodynamicparametersPanel);
            app.CheckBox_7.Text = '';
            app.ButtonGroup_6 = uibuttongroup(app.AerodynamicparametersPanel);
            app.steadyButton_2 = uiradiobutton(app.ButtonGroup_6);
            app.steadyButton_2.Text = 'steady';
            app.quasiSteadyButton_2 = uiradiobutton(app.ButtonGroup_6);
            app.quasiSteadyButton_2.Text = 'quasiSteady';
            app.unsteadyButton_2 = uiradiobutton(app.ButtonGroup_6);
            app.unsteadyButton_2.Text = 'unsteady';
            app.unsteadyButton_2.Value = true;
            app.ButtonGroup_7 = uibuttongroup(app.AerodynamicparametersPanel);
            app.bodyButton = uiradiobutton(app.ButtonGroup_7);
            app.bodyButton.Text = 'body';
            app.bodyButton.Value = true;
            app.inertialsButton = uiradiobutton(app.ButtonGroup_7);
            app.inertialsButton.Text = 'inertials';
            app.bodyanglesButton = uiradiobutton(app.ButtonGroup_7);
            app.bodyanglesButton.Text = 'bodyangles';
            app.DensityEditField_2Label = uilabel(app.AerodynamicparametersPanel);
            app.DensityEditField_2Label.Text = 'Density';
            app.DensityEditField = uieditfield(app.AerodynamicparametersPanel, 'numeric');
            app.DensityEditField.Value = 1.13;
            app.MachnumberEditFieldLabel = uilabel(app.AerodynamicparametersPanel);
            app.MachnumberEditFieldLabel.Text = 'Mach number';
            app.MachnumberEditField = uieditfield(app.AerodynamicparametersPanel, 'numeric');
            app.MachnumberEditField.Value = 0.1;
            app.DynVLMLabel = uilabel(app.AerodynamicparametersPanel);
            app.DynVLMLabel.Text = 'DynVLM';
            app.TypeLabel_2 = uilabel(app.AerodynamicparametersPanel);
            app.TypeLabel_2.Text = 'Type';
            app.AxesusedLabel = uilabel(app.AerodynamicparametersPanel);
            app.AxesusedLabel.Text = 'Axes used';
            app.SelectedtrimIDforVLMcorrectionEditField_3Label = uilabel(app.AerodynamicparametersPanel);
            app.SelectedtrimIDforVLMcorrectionEditField_3Label.Text = {'Selected trim'; 'ID for VLM'; 'correction'};
            app.SelectedtrimIDforVLMcorrectionEditField_3 = uieditfield(app.AerodynamicparametersPanel, 'numeric');
            app.TimemarchinganalysisTab = uitab(app.TabGroup);
            app.TimemarchinganalysisTab.Title = '5-Time marching analysis';
            app.TabGroup2 = uitabgroup(app.TimemarchinganalysisTab);
            app.PreprocesssimulationTab = uitab(app.TabGroup2);
            app.PreprocesssimulationTab.Title = '5.1-Preprocess simulation';
            app.UIAxes = uiaxes(app.PreprocesssimulationTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.CheckBox_8 = uicheckbox(app.PreprocesssimulationTab);
            app.CheckBox_8.Text = '';
            app.ButtonGroup_8 = uibuttongroup(app.PreprocesssimulationTab);
            app.steadyButton_3 = uiradiobutton(app.ButtonGroup_8);
            app.steadyButton_3.Text = 'steady';
            app.quasiSteadyButton_3 = uiradiobutton(app.ButtonGroup_8);
            app.quasiSteadyButton_3.Text = 'quasiSteady';
            app.unsteadyButton_3 = uiradiobutton(app.ButtonGroup_8);
            app.unsteadyButton_3.Text = 'unsteady';
            app.unsteadyButton_3.Value = true;
            app.MachnumberEditField_2Label = uilabel(app.PreprocesssimulationTab);
            app.MachnumberEditField_2Label.Text = 'Mach number';
            app.MachnumberEditField_2 = uieditfield(app.PreprocesssimulationTab, 'numeric');
            app.MachnumberEditField_2.Value = 0.1;
            app.CheckBox_9 = uicheckbox(app.PreprocesssimulationTab);
            app.CheckBox_9.Text = '';
            app.MonitorPointsEditFieldLabel = uilabel(app.PreprocesssimulationTab);
            app.MonitorPointsEditFieldLabel.Text = 'MonitorPoints';
            app.MonitorPointsEditField = uieditfield(app.PreprocesssimulationTab, 'text');
            app.MonitorPointsEditField.Value = '{40002,''g'',3,''Accelerometer at the tip LE'';40001,''g'',3,''Accelerometer at the tip TE'';40006,''g'',3,''Accelerometer at midpan LE'';40015,''g'',3,''Accelerometer at midpan elevator''}';
            app.GapBehaviourEditFieldLabel = uilabel(app.PreprocesssimulationTab);
            app.GapBehaviourEditFieldLabel.Text = 'GapBehaviour';
            app.GapBehaviourEditField = uieditfield(app.PreprocesssimulationTab, 'text');
            app.GapBehaviourEditField.Value = '{''freeplay'',''static'',''''}';
            app.CreatetimemarchingmodelButton = uibutton(app.PreprocesssimulationTab, 'push');
            app.CreatetimemarchingmodelButton.ButtonPushedFcn = createCallbackFcn(app, @CreatetimemarchingmodelButtonPushed, true);
            app.CreatetimemarchingmodelButton.Text = 'Create time marching model';
            app.NextButton_6 = uibutton(app.PreprocesssimulationTab, 'push');
            app.NextButton_6.ButtonPushedFcn = createCallbackFcn(app, @NextButton_6Pushed, true);
            app.NextButton_6.Text = 'Next';
            app.UsemodeButton = uibutton(app.PreprocesssimulationTab, 'state');
            app.UsemodeButton.Text = 'Use mode';
            app.ROMorderEditFieldLabel = uilabel(app.PreprocesssimulationTab);
            app.ROMorderEditFieldLabel.Text = 'ROM order';
            app.ROMorderEditField = uieditfield(app.PreprocesssimulationTab, 'numeric');
            app.ROMorderEditField.Value = 5;
            app.Label = uilabel(app.PreprocesssimulationTab);
            app.Label.Text = 'If ROM order is zero, an input will be requested in the command window';
            app.ChosenmodesEditFieldLabel = uilabel(app.PreprocesssimulationTab);
            app.ChosenmodesEditFieldLabel.Text = 'Chosen modes';
            app.ChosenmodesEditField = uieditfield(app.PreprocesssimulationTab, 'text');
            app.ChosenmodesEditField.Value = '[1,2,4,5,6,7,8,10,12,13]';
            app.DonotusemodeButton = uibutton(app.PreprocesssimulationTab, 'state');
            app.DonotusemodeButton.Text = 'Do not use mode';
            app.Label_6 = uilabel(app.PreprocesssimulationTab);
            app.Label_6.Text = {'If chosen modes are not specified, you will be interactively asked to choose them,'; 'aided by shape plots'};
            app.DynVLMLabel_2 = uilabel(app.PreprocesssimulationTab);
            app.DynVLMLabel_2.Text = 'DynVLM';
            app.DynVLMtypeLabel_2 = uilabel(app.PreprocesssimulationTab);
            app.DynVLMtypeLabel_2.Text = 'DynVLM type';
            app.PlotstochecktheapproximationLabel = uilabel(app.PreprocesssimulationTab);
            app.PlotstochecktheapproximationLabel.Text = 'Plots to check the approximation';
            app.SelectedtrimIDforVLMcorrectionEditField_4Label = uilabel(app.PreprocesssimulationTab);
            app.SelectedtrimIDforVLMcorrectionEditField_4Label.Text = 'Selected trim ID for VLM correction';
            app.SelectedtrimIDforVLMcorrectionEditField_4 = uieditfield(app.PreprocesssimulationTab, 'numeric');
            app.PerformanalysesTab = uitab(app.TabGroup2);
            app.PerformanalysesTab.Title = '5.2-Perform analyses';
            app.OptionsforthesteadyloadsPanel = uipanel(app.PerformanalysesTab);
            app.OptionsforthesteadyloadsPanel.Title = '5.2.3-Options for the steady loads';
            app.CheckBox_12 = uicheckbox(app.OptionsforthesteadyloadsPanel);
            app.CheckBox_12.Text = '';
            app.CheckBox_12.Value = true;
            app.ButtonGroup_13 = uibuttongroup(app.OptionsforthesteadyloadsPanel);
            app.meanAxesButton_2 = uiradiobutton(app.ButtonGroup_13);
            app.meanAxesButton_2.Text = 'meanAxes';
            app.groundedButton_2 = uiradiobutton(app.ButtonGroup_13);
            app.groundedButton_2.Text = 'grounded';
            app.groundedButton_2.Value = true;
            app.rigidButton_2 = uiradiobutton(app.ButtonGroup_13);
            app.rigidButton_2.Text = 'rigid';
            app.restrainedButton_2 = uiradiobutton(app.ButtonGroup_13);
            app.restrainedButton_2.Text = 'restrained';
            app.SelectedtrimIDforloadsEditFieldLabel = uilabel(app.OptionsforthesteadyloadsPanel);
            app.SelectedtrimIDforloadsEditFieldLabel.Text = 'Selected trim ID for loads';
            app.SelectedtrimIDforloadsEditField = uieditfield(app.OptionsforthesteadyloadsPanel, 'numeric');
            app.SelectedtrimIDforloadsEditField.Value = 1;
            app.CheckBox_13 = uicheckbox(app.OptionsforthesteadyloadsPanel);
            app.CheckBox_13.Text = '';
            app.StructuralloadsTheformatispoint1sorgdof12345or6loadpoint2Label = uilabel(app.OptionsforthesteadyloadsPanel);
            app.StructuralloadsTheformatispoint1sorgdof12345or6loadpoint2Label.Text = 'Structural loads. ';
            app.StructuralloadsDefinedEditField = uieditfield(app.OptionsforthesteadyloadsPanel, 'text');
            app.StructuralloadsDefinedEditField.Value = '{}';
            app.IntroduceflightloadsLabel_2 = uilabel(app.OptionsforthesteadyloadsPanel);
            app.IntroduceflightloadsLabel_2.Text = 'Introduce flight loads';
            app.TrimtypeforloadsLabel = uilabel(app.OptionsforthesteadyloadsPanel);
            app.TrimtypeforloadsLabel.Text = 'Trim type for loads';
            app.IntroducestructuralloadsLabel = uilabel(app.OptionsforthesteadyloadsPanel);
            app.IntroducestructuralloadsLabel.Text = 'Introduce structural loads';
            app.Theformatispoint1sorgdof12345or6loadpoint2Label = uilabel(app.OptionsforthesteadyloadsPanel);
            app.Theformatispoint1sorgdof12345or6loadpoint2Label.Text = {'The format is {point1, "s" or "g", '; 'dof (1,2,3,4,5 or 6), load;point2,...}'};
            app.CheckBox_18 = uicheckbox(app.OptionsforthesteadyloadsPanel);
            app.CheckBox_18.Text = '';
            app.IntroducegravityloadsLabel = uilabel(app.OptionsforthesteadyloadsPanel);
            app.IntroducegravityloadsLabel.Text = 'Introduce gravity loads';
            app.GravitydirectionEditFieldLabel = uilabel(app.OptionsforthesteadyloadsPanel);
            app.GravitydirectionEditFieldLabel.Text = 'Gravity direction';
            app.GravitydirectionEditField = uieditfield(app.OptionsforthesteadyloadsPanel, 'text');
            app.GravitydirectionEditField.Value = '[]';
            app.OptionsfortheoutputsPanel = uipanel(app.PerformanalysesTab);
            app.OptionsfortheoutputsPanel.Title = '5.2.2-Options for the outputs';
            app.InitialisationtimeEditFieldLabel = uilabel(app.OptionsfortheoutputsPanel);
            app.InitialisationtimeEditFieldLabel.Text = {'Initialisation time '; ''};
            app.InitialisationtimeEditField = uieditfield(app.OptionsfortheoutputsPanel, 'numeric');
            app.InitialisationtimeEditField.Value = 10;
            app.NumberofFFTwindowsEditFieldLabel = uilabel(app.OptionsfortheoutputsPanel);
            app.NumberofFFTwindowsEditFieldLabel.Text = 'Number of FFT windows';
            app.NumberofFFTwindowsEditField = uieditfield(app.OptionsfortheoutputsPanel, 'numeric');
            app.NumberofFFTwindowsEditField.Value = 19;
            app.FFTwindowlengthEditFieldLabel = uilabel(app.OptionsfortheoutputsPanel);
            app.FFTwindowlengthEditFieldLabel.Text = 'FFT window length';
            app.FFTwindowlengthEditField = uieditfield(app.OptionsfortheoutputsPanel, 'numeric');
            app.FFTwindowlengthEditField.Value = 20;
            app.timestepforFFTEditFieldLabel = uilabel(app.OptionsfortheoutputsPanel);
            app.timestepforFFTEditFieldLabel.Text = {'Time step for the output.'; ''};
            app.timestepforFFTEditField = uieditfield(app.OptionsfortheoutputsPanel, 'numeric');
            app.timestepforFFTEditField.Value = 0.001;
            app.OverlapofFFTwindowsEditFieldLabel = uilabel(app.OptionsfortheoutputsPanel);
            app.OverlapofFFTwindowsEditFieldLabel.Text = 'Overlap of FFT windows';
            app.OverlapofFFTwindowsEditField = uieditfield(app.OptionsfortheoutputsPanel, 'numeric');
            app.OverlapofFFTwindowsEditField.Value = -10;
            app.MaxfrequencyofinterestEditFieldLabel = uilabel(app.OptionsfortheoutputsPanel);
            app.MaxfrequencyofinterestEditFieldLabel.Text = 'Max frequency of interest';
            app.MaxfrequencyofinterestEditField = uieditfield(app.OptionsfortheoutputsPanel, 'numeric');
            app.MaxfrequencyofinterestEditField.Value = 150;
            app.ButtonGroup_12 = uibuttongroup(app.OptionsfortheoutputsPanel);
            app.rmsButton_2 = uiradiobutton(app.ButtonGroup_12);
            app.rmsButton_2.Text = 'rms';
            app.stdButton_2 = uiradiobutton(app.ButtonGroup_12);
            app.stdButton_2.Text = 'std';
            app.stdButton_2.Value = true;
            app.maxPeakButton = uiradiobutton(app.ButtonGroup_12);
            app.maxPeakButton.Text = 'maxPeak';
            app.CheckBox_10 = uicheckbox(app.OptionsfortheoutputsPanel);
            app.CheckBox_10.Text = '';
            app.CheckBox_10.Value = true;
            app.CheckBox_11 = uicheckbox(app.OptionsfortheoutputsPanel);
            app.CheckBox_11.Text = '';
            app.CheckBox_11.Value = true;
            app.nooutoputclculationLabel = uilabel(app.OptionsfortheoutputsPanel);
            app.nooutoputclculationLabel.Text = '(no outoput clculation)';
            app.RecoputedequidistandassimulinkusesanadaptivestrategyLabel = uilabel(app.OptionsfortheoutputsPanel);
            app.RecoputedequidistandassimulinkusesanadaptivestrategyLabel.Text = {'Recoputed equidistand as'; 'simulink uses an adaptive'; 'strategy'};
            app.AmplitudedefinitionfortheLCOLabel = uilabel(app.OptionsfortheoutputsPanel);
            app.AmplitudedefinitionfortheLCOLabel.Text = 'Amplitude definition for the LCO';
            app.PlotmaxrealeigenvalueLabel = uilabel(app.OptionsfortheoutputsPanel);
            app.PlotmaxrealeigenvalueLabel.Text = 'Plot max real eigenvalue';
            app.PlotfixedpointsofthesystemLabel = uilabel(app.OptionsfortheoutputsPanel);
            app.PlotfixedpointsofthesystemLabel.Text = 'Plot fixed points of the system';
            app.OptionsforthesimulationPanel = uipanel(app.PerformanalysesTab);
            app.OptionsforthesimulationPanel.Title = '5.2.1-Options for the simulation';
            app.DensityEditField_2Label_2 = uilabel(app.OptionsforthesimulationPanel);
            app.DensityEditField_2Label_2.Text = 'Density';
            app.DensityEditField_2 = uieditfield(app.OptionsforthesimulationPanel, 'numeric');
            app.DensityEditField_2.Value = 1.13;
            app.SpeedvectorEditFieldLabel = uilabel(app.OptionsforthesimulationPanel);
            app.SpeedvectorEditFieldLabel.Text = 'Speed vector';
            app.SpeedvectorEditField = uieditfield(app.OptionsforthesimulationPanel, 'text');
            app.SpeedvectorEditField.Value = '(14:2:50)';
            app.ButtonGroup_9 = uibuttongroup(app.OptionsforthesimulationPanel);
            app.bothButton = uiradiobutton(app.ButtonGroup_9);
            app.bothButton.Text = 'both';
            app.bothButton.Value = true;
            app.increasingButton = uiradiobutton(app.ButtonGroup_9);
            app.increasingButton.Text = 'increasing';
            app.descresingButton = uiradiobutton(app.ButtonGroup_9);
            app.descresingButton.Text = 'descresing';
            app.InitialamplitudefactorEditFieldLabel = uilabel(app.OptionsforthesimulationPanel);
            app.InitialamplitudefactorEditFieldLabel.Text = 'Initial amplitude factor';
            app.InitialamplitudefactorEditField = uieditfield(app.OptionsforthesimulationPanel, 'numeric');
            app.InitialamplitudefactorEditField.Value = 2;
            app.ButtonGroup_10 = uibuttongroup(app.OptionsforthesimulationPanel);
            app.previousButton = uiradiobutton(app.ButtonGroup_10);
            app.previousButton.Text = 'previous';
            app.previousButton.Value = true;
            app.linearButton = uiradiobutton(app.ButtonGroup_10);
            app.linearButton.Text = 'linear';
            app.splineButton = uiradiobutton(app.ButtonGroup_10);
            app.splineButton.Text = 'spline';
            app.BehaviourLabel = uilabel(app.OptionsforthesimulationPanel);
            app.BehaviourLabel.Text = 'Behaviour';
            app.InterpolationLabel = uilabel(app.OptionsforthesimulationPanel);
            app.InterpolationLabel.Text = 'Interpolation';
            app.NonlinearitydefinitionLabel = uilabel(app.OptionsforthesimulationPanel);
            app.NonlinearitydefinitionLabel.Text = '----------- Nonlinearity definition -----------';
            app.NominalstiffnessEditFieldLabel = uilabel(app.OptionsforthesimulationPanel);
            app.NominalstiffnessEditFieldLabel.Text = 'Nominal stiffness';
            app.NominalstiffnessEditField = uieditfield(app.OptionsforthesimulationPanel, 'text');
            app.gatherfromdescribingfunctionoptionsButton = uibutton(app.OptionsforthesimulationPanel, 'push');
            app.gatherfromdescribingfunctionoptionsButton.ButtonPushedFcn = createCallbackFcn(app, @gatherfromdescribingfunctionoptionsButtonPushed, true);
            app.gatherfromdescribingfunctionoptionsButton.Text = 'gather from describing function options';
            app.GapdimensionsEditFieldLabel = uilabel(app.OptionsforthesimulationPanel);
            app.GapdimensionsEditFieldLabel.Text = 'Gap dimensions';
            app.GapdimensionsEditField = uieditfield(app.OptionsforthesimulationPanel, 'text');
            app.ButtonGroup_11 = uibuttongroup(app.OptionsforthesimulationPanel);
            app.previousButton_2 = uiradiobutton(app.ButtonGroup_11);
            app.previousButton_2.Text = 'previous';
            app.previousButton_2.Value = true;
            app.linearButton_2 = uiradiobutton(app.ButtonGroup_11);
            app.linearButton_2.Text = 'linear';
            app.splineButton_2 = uiradiobutton(app.ButtonGroup_11);
            app.splineButton_2.Text = 'spline';
            app.TypeLabel_3 = uilabel(app.OptionsforthesimulationPanel);
            app.TypeLabel_3.Text = 'Type';
            app.Label_7 = uilabel(app.OptionsforthesimulationPanel);
            app.Label_7.Text = {'If different types must be used,'; 'specify it below. Otherwise the same'; 'type will be used for all as defined by'; 'the button list'};
            app.EditField = uieditfield(app.OptionsforthesimulationPanel, 'text');
            app.LaunchtimemarchingsimulationPanel = uipanel(app.PerformanalysesTab);
            app.LaunchtimemarchingsimulationPanel.Title = '5.2.4-Launch time marching simulation';
            app.PerformtimemarchinganalysesButton = uibutton(app.LaunchtimemarchingsimulationPanel, 'push');
            app.PerformtimemarchinganalysesButton.ButtonPushedFcn = createCallbackFcn(app, @PerformtimemarchinganalysesButtonPushed, true);
            app.PerformtimemarchinganalysesButton.Text = 'Perform time marching analyses';
            app.NextButton_5 = uibutton(app.LaunchtimemarchingsimulationPanel, 'push');
            app.NextButton_5.ButtonPushedFcn = createCallbackFcn(app, @NextButton_5Pushed, true);
            app.NextButton_5.Text = 'Next';
            app.NumberofprocessorsEditFieldLabel = uilabel(app.LaunchtimemarchingsimulationPanel);
            app.NumberofprocessorsEditFieldLabel.Text = 'Number of processors';
            app.NumberofprocessorsEditField = uieditfield(app.LaunchtimemarchingsimulationPanel, 'numeric');
            app.NumberofprocessorsEditField.Value = 1;
            app.MainplotsTab = uitab(app.TabGroup);
            app.MainplotsTab.Title = '6-Main plots';
            app.UIAxes2 = uiaxes(app.MainplotsTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.MainplotsTab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.MainplotsTab);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.MainplotsTab);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.CheckBox_14 = uicheckbox(app.MainplotsTab);
            app.CheckBox_14.Text = '';
            app.CheckBox_14.Value = true;
            app.OtherwisethefullgapisusedforthenormalisationLabel = uilabel(app.MainplotsTab);
            app.OtherwisethefullgapisusedforthenormalisationLabel.Text = {'Otherwise the full gap is '; 'used for the normalisation'};
            app.CheckBox_15 = uicheckbox(app.MainplotsTab);
            app.CheckBox_15.Text = '';
            app.ValueforwindspeednormalisationEditFieldLabel = uilabel(app.MainplotsTab);
            app.ValueforwindspeednormalisationEditFieldLabel.Text = 'Value for wind speed normalisation';
            app.ValueforwindspeednormalisationEditField = uieditfield(app.MainplotsTab, 'numeric');
            app.ValueforwindspeednormalisationEditField.Value = 1;
            app.CheckBox_16 = uicheckbox(app.MainplotsTab);
            app.CheckBox_16.Text = '';
            app.CheckBox_16.Value = true;
            app.CheckBox_17 = uicheckbox(app.MainplotsTab);
            app.CheckBox_17.Text = '';
            app.ButtonGroup_14 = uibuttongroup(app.MainplotsTab);
            app.SingleButton = uiradiobutton(app.ButtonGroup_14);
            app.SingleButton.Text = 'Single';
            app.SingleButton.Value = true;
            app.BifurcationButton = uiradiobutton(app.ButtonGroup_14);
            app.BifurcationButton.Text = 'Bifurcation';
            app.ComputemainoutputsButton = uibutton(app.MainplotsTab, 'push');
            app.ComputemainoutputsButton.ButtonPushedFcn = createCallbackFcn(app, @ComputemainoutputsButtonPushed, true);
            app.ComputemainoutputsButton.Text = {'Compute main '; 'outputs'};
            app.HalfgapnormalisationfortheLCOamplitudeLabel = uilabel(app.MainplotsTab);
            app.HalfgapnormalisationfortheLCOamplitudeLabel.Text = {'Half gap normalisation for the'; ' LCO amplitude'};
            app.NonlinearitytobeusedEditFieldLabel = uilabel(app.MainplotsTab);
            app.NonlinearitytobeusedEditFieldLabel.Text = 'Nonlinearity to be used';
            app.NonlinearitytobeusedEditField = uieditfield(app.MainplotsTab, 'numeric');
            app.PlotasinglegapforthedescribingfunctionresultsLabel = uilabel(app.MainplotsTab);
            app.PlotasinglegapforthedescribingfunctionresultsLabel.Text = {'Plot a single gap for the '; 'describing function results'};
            app.NormalisealsothemonitorpointswiththegapsizeLabel = uilabel(app.MainplotsTab);
            app.NormalisealsothemonitorpointswiththegapsizeLabel.Text = {'Normalise also the monitor points '; 'with the gap size'};
            app.NormalisealsotheforcestorquesatthegappointsbythegapsizeLabel = uilabel(app.MainplotsTab);
            app.NormalisealsotheforcestorquesatthegappointsbythegapsizeLabel.Text = {'Normalise also the forces / torques'; ' at the gap points by the gap size'};
            app.PlottypeLabel = uilabel(app.MainplotsTab);
            app.PlottypeLabel.Text = 'Plot type';
            app.ExitButton = uibutton(app.MainplotsTab, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.ExitsavingconfigurationButton = uibutton(app.MainplotsTab, 'push');
            app.ExitsavingconfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @ExitsavingconfigurationButtonPushed, true);
            app.ExitsavingconfigurationButton.Text = 'Exit saving configuration';
            app.TextArea = uitextarea(app.NeoLCOUIFigure);
            app.Image = uiimage(app.NeoLCOUIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'logo.jpeg');
            app.LogoPanel = uipanel(app.NeoLCOUIFigure);
            app.LogoPanel.Title = 'Logo';
            app.Image2 = uiimage(app.LogoPanel);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'logo.jpeg');
            % Show the figure after all components are created
            app.NeoLCOUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NeoLCOUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NeoLCOUIFigure)
        end
    end
end